classdef WEB_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CatlogodePlantasMenu        matlab.ui.container.Menu
        ConfiguracindeSensoresMenu  matlab.ui.container.Menu
        MonitoreoMenu               matlab.ui.container.Menu
        AgregarButton               matlab.ui.control.Button
        EliminarButton              matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        CalibrarButton              matlab.ui.control.Button
        DetenerButton               matlab.ui.control.Button
        SensornocalibradoLampLabel  matlab.ui.control.Label
        SensornocalibradoLamp       matlab.ui.control.Lamp
        PresininicialmbarEditFieldLabel  matlab.ui.control.Label
        PresininicialmbarEditField  matlab.ui.control.NumericEditField
        MrgendeerrorEditFieldLabel  matlab.ui.control.Label
        MrgendeerrorEditField       matlab.ui.control.NumericEditField
    end

    properties (Access = public)
         socket
         AgregarApp              % Dialog box app
         idPlanta
         Nombre
         Sensor
         Presion
         Habilitado
         Descripcion
         
         selected
    end
    
    methods (Access = public)
        
        function sendData(app,data)
            fwrite(app.socket, data)
        end
        
        function data = receiveData(app)
            data = fgetl(app.socket);
        end
        
        function updateTable(app)
            app.sendData('getPlants')
            tabla = app.receiveData();
            tabla = replace(tabla,"u'","");
            tabla = replace(tabla,"'","");
            tabla = replace(tabla,"None"," ");
            tabla = replace(tabla,"[(","");
            tabla = replace(tabla,")]","");
            tabla = split(tabla,'), (');
            tabla = split(tabla,',');
            tabla = replace(tabla,"'","");
            app.idPlanta = tabla(:,1);
            app.idPlanta = cellfun(@str2num,app.idPlanta);
            app.Nombre = tabla(:,2);
            app.Sensor = tabla(:,3);
            app.Sensor = categorical(app.Sensor,{'1';'2'},"Protected",true);
            app.Presion = tabla(:,4);
            app.Presion = cellfun(@str2num,app.Presion);
            app.Habilitado = tabla(:,5);
            app.Habilitado = cellfun(@str2num,app.Habilitado);
            app.Habilitado = logical(app.Habilitado);
            app.Descripcion = tabla(:,6);
            tabla = table(app.Nombre,app.Sensor,app.Descripcion,app.Habilitado);
            app.UITable.Data = tabla;
        end
        
        function modifyPlant(app,indice,idPlanta,newData)
            if indice == 1
                newData = char(newData);
                data = strcat('modifyPlant',',',idPlanta,',','Nombre',',',newData);
                data = char(data);
                app.sendData(data)
            elseif indice == 2
                newData = char(newData);
                data = strcat('modifyPlant',',',idPlanta,',','Sensor',',',newData);
                data = char(data);
                app.sendData(data)
            elseif indice == 3
                newData = char(newData);
                data = strcat('modifyPlant',',',idPlanta,',','Descripcion',',',newData);
                data = char(data);
                app.sendData(data)
            else 
                newData = num2str(newData);
                data = strcat('modifyPlant',',',idPlanta,',','Habilitado',',',newData);
                data = char(data);
                app.sendData(data)
            end
            
        end
        
        function confirmClose(app, src, event)
            % Determine which dialog button the user clicked
            answer = event.SelectedOption;
            
            % Close the app if the user clicks OK
            if strcmp(answer,'OK')
                id = num2str(app.idPlanta(app.selected));
                app.deletePlant(id)
                app.receiveData()
                app.updateTable()
                app.EliminarButton.Enable = 'off';
            end
            app.EliminarButton.Enable = 'off';
        end
        
        function deletePlant(app,idPlanta)
             data = strcat('deletePlant',',',idPlanta);
             data = char(data);
             app.sendData(data)
        end
        
        function updateEnabledPlants(app)
            app.sendData('getEnabledPlants')
            tabla = app.receiveData();
            tabla = replace(tabla,"u'","");
            tabla = replace(tabla,"'","");
            tabla = replace(tabla,"None"," ");
            tabla = replace(tabla,"[(","");
            tabla = replace(tabla,")]","");
            tabla = split(tabla,'), (');
            tabla = split(tabla,',');
            tabla = replace(tabla,"'","");
            app.idPlanta = tabla(:,1);
            app.idPlanta = cellfun(@str2num,app.idPlanta);
            app.Nombre = tabla(:,2);
            app.Sensor = tabla(:,3);
            app.Sensor = categorical(app.Sensor,{'1';'2'},"Protected",true);
            app.Presion = tabla(:,4);
            app.Presion = cellfun(@str2num,app.Presion);
            tabla = table(app.Nombre,app.Sensor,app.Presion);
            app.UITable.Data = tabla;
        end
        
        function selectedPlants(app)
            count1 = 0;
            count2 = 0;
            [m,n] = size(app.Sensor);
            if m > 2
                return false
            end
            for i = 1 : m
                if app.Sensor(i,1) == 1
                    count1 = count1 + 1;
                elseif pp.Sensor(i,1) == 2
                    count2 = count2 + 1;
                end
            end
            if count1 > 1 | count2 > 1
                return false
            else
                return true
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            close all
            
            app.EliminarButton.Enable = 'off';
            app.socket = tcpip('192.168.7.140', 30000, 'NetworkRole', 'client');
            fopen(app.socket)
            
            app.updateTable()
            
        end

        % Button pushed function: AgregarButton
        function AgregarButtonPushed(app, event)
            
            app.CatlogodePlantasMenu.Enable = 'off';
            app.AgregarButton.Enable = 'off';
            app.EliminarButton.Enable = 'off';
            app.UITable.Enable = 'off';
            app.ConfiguracindeSensoresMenu.Enable = 'off';
            app.MonitoreoMenu.Enable = 'off';
            
            app.AgregarApp = agregar(app);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fclose(app.socket)
            delete(app)
            
        end

        % Callback function
        function ModificarButtonPushed(app, event)
                 
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            id = num2str(app.idPlanta(indices(1)));
            app.modifyPlant(indices(2),id,newData)
            app.receiveData()
            app.updateTable()
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.EliminarButton.Enable = 'on';
            app.CalibrarButton.Enable = 'on';
            indices = event.Indices;
            app.selected = indices(1);
        end

        % Button pushed function: EliminarButton
        function EliminarButtonPushed(app, event)
            plant = app.Nombre(app.selected);
            msg = strcat('¿Estas seguro de eliminar ',plant,'?');
            uiconfirm(app.UIFigure,msg,'Eliminar Planta','CloseFcn',@app.confirmClose);
            
        end

        % Menu selected function: ConfiguracindeSensoresMenu
        function ConfiguracindeSensoresMenuSelected(app, event)
            app.AgregarButton.Visible = 'off';
            app.EliminarButton.Visible = 'off';
            app.CalibrarButton.Visible = 'on';
            app.CalibrarButton.Enable = 'off';
            app.SensornocalibradoLamp.Visible = 'on';
            app.SensornocalibradoLamp.Enable = 'off';
            app.PresininicialmbarEditField.Visible = 'on';
            app.PresininicialmbarEditFieldLabel.Visible = 'on';
            app.MrgendeerrorEditField.Visible = 'on';
            app.MrgendeerrorEditFieldLabel.Visible = 'on';
            app.UITable.ColumnName = {'Nombre','Sensor','Presión'};
            app.updateEnabledPlants()
            
        end

        % Menu selected function: CatlogodePlantasMenu
        function CatlogodePlantasMenuSelected(app, event)
            app.AgregarButton.Visible = 'on';
            app.EliminarButton.Visible = 'on';
            app.EliminarButton.Enable = 'off';
            app.CalibrarButton.Visible = 'off';
            app.SensornocalibradoLamp.Visible = 'off';
            app.PresininicialmbarEditField.Visible = 'off';
            app.PresininicialmbarEditFieldLabel.Visible = 'off';
            app.MrgendeerrorEditField.Visible = 'off';
            app.MrgendeerrorEditFieldLabel.Visible = 'off';
            app.UITable.ColumnName = {'Nombre','Sensor','Descripción','Habilitar'};
            app.updateTable()
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CatlogodePlantasMenu = uimenu(app.UIFigure);
            app.CatlogodePlantasMenu.MenuSelectedFcn = createCallbackFcn(app, @CatlogodePlantasMenuSelected, true);
            app.CatlogodePlantasMenu.Text = 'Catálogo de Plantas';
            app.ConfiguracindeSensoresMenu = uimenu(app.UIFigure);
            app.ConfiguracindeSensoresMenu.MenuSelectedFcn = createCallbackFcn(app, @ConfiguracindeSensoresMenuSelected, true);
            app.ConfiguracindeSensoresMenu.Text = 'Configuración de Sensores';
            app.MonitoreoMenu = uimenu(app.UIFigure);
            app.MonitoreoMenu.Text = 'Monitoreo';
            app.AgregarButton = uibutton(app.UIFigure, 'push');
            app.AgregarButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarButtonPushed, true);
            app.AgregarButton.Text = 'Agregar';
            app.EliminarButton = uibutton(app.UIFigure, 'push');
            app.EliminarButton.ButtonPushedFcn = createCallbackFcn(app, @EliminarButtonPushed, true);
            app.EliminarButton.Text = 'Eliminar';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Nombre'; 'Sensor'; 'Descripción'; 'Habilitar'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = false;
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.CalibrarButton = uibutton(app.UIFigure, 'push');
            app.CalibrarButton.Visible = 'off';
            app.CalibrarButton.Text = 'Calibrar';
            app.DetenerButton = uibutton(app.UIFigure, 'push');
            app.DetenerButton.Visible = 'off';
            app.DetenerButton.Text = 'Detener';
            app.SensornocalibradoLampLabel = uilabel(app.UIFigure);
            app.SensornocalibradoLampLabel.Visible = 'off';
            app.SensornocalibradoLampLabel.Text = 'Sensor no calibrado';
            app.SensornocalibradoLamp = uilamp(app.UIFigure);
            app.SensornocalibradoLamp.Visible = 'off';
            app.PresininicialmbarEditFieldLabel = uilabel(app.UIFigure);
            app.PresininicialmbarEditFieldLabel.Visible = 'off';
            app.PresininicialmbarEditFieldLabel.Text = 'Presión inicial (mbar)';
            app.PresininicialmbarEditField = uieditfield(app.UIFigure, 'numeric');
            app.PresininicialmbarEditField.Limits = [1000 10000];
            app.PresininicialmbarEditField.Visible = 'off';
            app.PresininicialmbarEditField.Value = 1000;
            app.MrgendeerrorEditFieldLabel = uilabel(app.UIFigure);
            app.MrgendeerrorEditFieldLabel.Visible = 'off';
            app.MrgendeerrorEditFieldLabel.Text = 'Márgen de error (%)';
            app.MrgendeerrorEditField = uieditfield(app.UIFigure, 'numeric');
            app.MrgendeerrorEditField.Limits = [10 50];
            app.MrgendeerrorEditField.Visible = 'off';
            app.MrgendeerrorEditField.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WEB_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end